classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TtimeandEnergycollibrationTab  matlab.ui.container.Tab
        yrangeEditField2               matlab.ui.control.EditField
        foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2  matlab.ui.control.Label
        StartCheckBox_2                matlab.ui.control.CheckBox
        TimecollibrationPanel          matlab.ui.container.Panel
        rightEditField                 matlab.ui.control.NumericEditField
        rightEditFieldLabel            matlab.ui.control.Label
        phaseSpinner                   matlab.ui.control.Spinner
        phaseSpinnerLabel              matlab.ui.control.Label
        leftEditField                  matlab.ui.control.NumericEditField
        leftEditFieldLabel             matlab.ui.control.Label
        DotimecollibrationButton       matlab.ui.control.Button
        EnergycollibrationPanel        matlab.ui.container.Panel
        downEditField                  matlab.ui.control.NumericEditField
        downEditFieldLabel             matlab.ui.control.Label
        currentSpinner                 matlab.ui.control.Spinner
        currentSpinnerLabel            matlab.ui.control.Label
        upEditField                    matlab.ui.control.NumericEditField
        upEditFieldLabel               matlab.ui.control.Label
        DoenergycollibrationButton     matlab.ui.control.Button
        DosinglemeasurmentButton       matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        pulsereconstructionTab         matlab.ui.container.Tab
        yrangeEditField                matlab.ui.control.EditField
        foronlinemeasurmentwritethecorrectrangeEditFieldLabel  matlab.ui.control.Label
        nshotsEditField                matlab.ui.control.NumericEditField
        nshotsEditFieldLabel           matlab.ui.control.Label
        analysingimageButton           matlab.ui.control.Button
        feedbackonphaseCheckBox        matlab.ui.control.CheckBox
        PulsereconstructionButton      matlab.ui.control.Button
        collectingdatalasingoffButton  matlab.ui.control.Button
        collectingdatalasingonButton   matlab.ui.control.Button
        takebackgrounddataButton       matlab.ui.control.Button
        StartCheckBox                  matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        onlinemeasurmentTab            matlab.ui.container.Tab
        STOPCheckBox                   matlab.ui.control.CheckBox
        STARTCheckBox                  matlab.ui.control.CheckBox
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
    end

 % select cameras

properties (Access = public)
    rep_rate  =10;
    num_bgr=10;
    addr_cam        = 'FLASH.DIAG/CAMERA/OTR9FL2XTDS/';
    addr_charge='FLASH.DIAG/TOROID/7FL2XTDS/CHARGE.FLASH2';
    addr_TDSphase='FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE';
    add_COFM='FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.CM'
    add2='FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.MEAN';
    add_phase='FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE'
    addr_dipol='TTF2.MAGNETS/DIPOLE/D4FL2XTDS/PS'
end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.phaseSpinner.Value=getfield(doocsread(app.addr_TDSphase) , 'data')
            app.rightEditField.Value=app.phaseSpinner.Value+2
            app.leftEditField.Value=app.phaseSpinner.Value-2
            %%
            app.currentSpinner.Value=getfield(doocsread(app.addr_dipol) , 'data')
            app.upEditField.Value=app.currentSpinner.Value+2
            app.downEditField.Value=app.currentSpinner.Value-2
%app.nshotsEditField
            num_shot=app.nshotsEditField.Value

        end

        % Callback function: StartCheckBox, UIAxes, feedbackonphaseCheckBox
        function UIAxesButtonDown(app, event)
            % loop
            % here we just show the image on the screen
            if app.StartCheckBox.Value
                    flag=true;
            else flag=false;
            end

            if app.feedbackonphaseCheckBox.Value
                
                x1=getfield(doocsread(app.add_COFM), 'data')
                phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                tem=doocswrite(app.add_phase, phase_XTDS-0.5)
                x2=getfield(doocsread(app.add_COFM), 'data')
                if x2-x1 >0
                    phase_feedback=1 ;% or -1
                elseif x2-x1 <0
                    phase_feedback=-1;
                end 
            end 
            jj=0
 
            while app.StartCheckBox.Value
%                 if app.StartCheckBox.Value
%                     flag=app.StartCheckBox.Value
%                 else
%                     flag=false
%                 end

                jj=jj+1

                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;
    
                a=str2num(app.yrangeEditField.Value);
                I= contourf(app.UIAxes,img_sig(end:-1:a,:,jj) ,'EdgeColor','none');
                if mod(jj,5)==0
                    if app.feedbackonphaseCheckBox.Value
                        x=getfield(doocsread(app.add_COFM), 'data')
                        if mean(x) > 8
                            phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                            tem=doocswrite(app.add_phase, phase_XTDS-phase_feedback)
                        end
                        if mean(x) < 5
                            phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                            tem=doocswrite(app.add_phase, phase_XTDS+phase_feedback)
                        end
                    end
                end
                pause(2/app.rep_rate)

            end
        end

        % Button pushed function: takebackgrounddataButton
        function takebackgrounddataButtonPushed(app, event)
            % collecting back ground image
            delete background_img.mat
            
        
            % take bgr
            for jj = 1:app.num_bgr

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);

                img_bgr(:,:,jj)     = ddd_read.data.val_val;
                t(jj)=ddd_read.timestamp;
                
                % compared it with last measurement
                 if jj > 1
                     while t(jj) == t(jj-1)
                         ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                         img_bgr(:,:,jj)       = ddd_read.data.val_val;
                         display([' - (', num2str(jj), ') same data ... wait ...']);
                         pause(1/app.rep_rate)
                     end
                 end

                pause(1./app.rep_rate)

            end

            % compute mean()
            if app.num_bgr == 1
                img_bgr_mean = squeeze(img_bgr);
            else
                img_bgr_mean = squeeze(mean(img_bgr, 3));
            end
            %Saved back ground file

            save('background_img.mat', 'img_bgr_mean');

            display(' - background data taken')

        end

        % Button pushed function: collectingdatalasingoffButton
        function collectingdatalasingoffButtonPushed(app, event)
            % collecting image shot Lasing off
            delete imag_shots_lasing_off.mat
            
       % take bgr
            for jj = 1:app.num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                
                ts(jj)=ddd_read.timestamp;
                charge(jj)=doocsread(app.addr_charge);
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_off.mat', 'img_mean', 'img','ts', 'charge');
            display(' - image data taken')

        end

        % Button pushed function: collectingdatalasingonButton
        function collectingdatalasingonButtonPushed(app, event)
            
               % collecting image shot lasing on 
            delete imag_shots_lasing_on.mat
          
            % take bgr
            for jj = 1:app.num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                charge(jj)=doocsread(app.addr_charge);
                ts(jj)=ddd_read.timestamp;
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_on.mat', 'img_mean', 'img', 'ts', 'charge');
            display(' - image data taken')

        end

        % Button pushed function: analysingimageButton
        function analysingimageButtonPushed(app, event)
            %address=~/
            %cd %(address)
            %filename=uigetfile
            
            [filen, path]=uigetfile;
            filename=[path, filen]
            assignin('base', 'filename',  filename)
            a=str2num(app.yrangeEditField.Value);
            output_filename=analysing_image(filename, a)

            display(' - data saved');
            Title='LPS analysis'
            text='NONE'
            figure(10012)
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,output_filename, Title, text ));

        end

        % Button pushed function: PulsereconstructionButton
        function PulsereconstructionButtonPushed(app, event)
            close all
            a=str2num(app.yrangeEditField.Value);
            filename= Pulse_reconstruction(a)
            figure(1044)
            Title='Pulse Recunstruction'
            text='NONE'
            uicontrol('String', 'Print', 'Callback', @(btn,~)printinlogbook(btn,filename, Title, text ));
            figure(1)
            Title='Pulse Recunstruction'
            text='NONE'
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,filename, Title, text ));
        end

        % Callback function: StartCheckBox_2, UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            % loop
            % here we just show the image on the screen
            flag=true

            jj=0
            while flag
                if app.StartCheckBox_2.Value
                    flag=true
                else
                    flag=false
                end
                jj=jj+1

                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;

                I= contourf(app.UIAxes_2,img_sig(:,:,jj) ,'EdgeColor','none');
                pause(2/app.rep_rate)
            end
        end

        % Value changing function: phaseSpinner
        function phaseSpinnerValueChanging(app, event)
       
            app.rightEditField.Value=app.phaseSpinner.Value+2
            app.leftEditField.Value=app.phaseSpinner.Value-2     
            tem=doocswrite(app.add_phase, app.phaseSpinner.Value-1)
            display(app.phaseSpinner.Value)
        end

        % Value changed function: currentSpinner
        function currentSpinnerValueChanged(app, event)
            %value = app.currentSpinner.Value;
            %tem=doocswrite(app.add_dipol, app.currentSpinner.Value)
            app.upEditField.Value=app.currentSpinner.Value-1
            app.downEditField.Value=app.currentSpinner.Value+1

        end

        % Button pushed function: DotimecollibrationButton
        function DotimecollibrationButtonPushed(app, event)
            a=str2num(app.leftEditField.Value)
            b=str2num(app.rightEditField.Value)  
            num_sig=10
            ylimit=str2num(app.yrangeEditField2.Value);
            get_timecolibration(a, b, num_sig, ylimit)
            % Done should be test 
        end

        % Button pushed function: DoenergycollibrationButton
        function DoenergycollibrationButtonPushed(app, event)
            a=str2num(app.upEditField.Value)
            b=str2num(app.downEditField.Value)  
            num_sig=10
            ylimit=str2num(app.yrangeEditField2.Value);
            get_energycolibration(a, b, num_sig, ylimit)
            % 30 Nove 2023 will be done 
        end

        % Button pushed function: DosinglemeasurmentButton
        function DosinglemeasurmentButtonPushed(app, event)
            % here we get one screan shot and analysis it, 
            % remmebr single shot 
            % read
            ddd_read     = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
            img(:,:)     = ddd_read.data.val_val;

            ts=ddd_read.timestamp;
            charge=doocsread(app.addr_charge);
            % compared it with last measurement

            save('imag_shot.mat', 'img','ts', 'charge');
            display(' - image data taken')
            a=str2num(app.yrangeEditField2.Value);
            output_filename=analysing_image('imag_shot.mat', a)
            display(' - data saved');
            Title='LPS analysis'
            text='NONE'
            figure(10012)
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,output_filename, Title, text ));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TtimeandEnergycollibrationTab = uitab(app.TabGroup);
            app.TtimeandEnergycollibrationTab.Title = 'Ttime and Energy collibration ';
            app.UIAxes_2 = uiaxes(app.TtimeandEnergycollibrationTab);
            title(app.UIAxes_2, 'image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.DosinglemeasurmentButton = uibutton(app.TtimeandEnergycollibrationTab, 'push');
            app.DosinglemeasurmentButton.ButtonPushedFcn = createCallbackFcn(app, @DosinglemeasurmentButtonPushed, true);
            app.DosinglemeasurmentButton.Text = 'Do single measurment';
            app.EnergycollibrationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.EnergycollibrationPanel.Title = 'Energy collibration';
            app.DoenergycollibrationButton = uibutton(app.EnergycollibrationPanel, 'push');
            app.DoenergycollibrationButton.ButtonPushedFcn = createCallbackFcn(app, @DoenergycollibrationButtonPushed, true);
            app.DoenergycollibrationButton.Text = 'Do energy collibration';
            app.upEditFieldLabel = uilabel(app.EnergycollibrationPanel);
            app.upEditFieldLabel.Text = 'up';
            app.upEditField = uieditfield(app.EnergycollibrationPanel, 'numeric');
            app.currentSpinnerLabel = uilabel(app.EnergycollibrationPanel);
            app.currentSpinnerLabel.Text = 'current';
            app.currentSpinner = uispinner(app.EnergycollibrationPanel);
            app.currentSpinner.ValueChangedFcn = createCallbackFcn(app, @currentSpinnerValueChanged, true);
            app.downEditFieldLabel = uilabel(app.EnergycollibrationPanel);
            app.downEditFieldLabel.Text = 'down';
            app.downEditField = uieditfield(app.EnergycollibrationPanel, 'numeric');
            app.TimecollibrationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.TimecollibrationPanel.Title = 'Time collibration';
            app.DotimecollibrationButton = uibutton(app.TimecollibrationPanel, 'push');
            app.DotimecollibrationButton.ButtonPushedFcn = createCallbackFcn(app, @DotimecollibrationButtonPushed, true);
            app.DotimecollibrationButton.Text = 'Do time collibration';
            app.leftEditFieldLabel = uilabel(app.TimecollibrationPanel);
            app.leftEditFieldLabel.Text = 'left';
            app.leftEditField = uieditfield(app.TimecollibrationPanel, 'numeric');
            app.phaseSpinnerLabel = uilabel(app.TimecollibrationPanel);
            app.phaseSpinnerLabel.Text = 'phase';
            app.phaseSpinner = uispinner(app.TimecollibrationPanel);
            app.phaseSpinner.ValueChangingFcn = createCallbackFcn(app, @phaseSpinnerValueChanging, true);
            app.phaseSpinner.Value = 120;
            app.rightEditFieldLabel = uilabel(app.TimecollibrationPanel);
            app.rightEditFieldLabel.Text = 'right';
            app.rightEditField = uieditfield(app.TimecollibrationPanel, 'numeric');
            app.StartCheckBox_2 = uicheckbox(app.TtimeandEnergycollibrationTab);
            app.StartCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.StartCheckBox_2.Text = 'Start ';
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2 = uilabel(app.TtimeandEnergycollibrationTab);
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2.Text = 'for online measurment write the correct  range ';
            app.yrangeEditField2 = uieditfield(app.TtimeandEnergycollibrationTab, 'text');
            app.yrangeEditField2.Value = '100';
            app.pulsereconstructionTab = uitab(app.TabGroup);
            app.pulsereconstructionTab.Title = 'pulse reconstruction';
            app.UIAxes = uiaxes(app.pulsereconstructionTab);
            title(app.UIAxes, 'image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox = uicheckbox(app.pulsereconstructionTab);
            app.StartCheckBox.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox.Text = 'Start ';
            app.takebackgrounddataButton = uibutton(app.pulsereconstructionTab, 'push');
            app.takebackgrounddataButton.ButtonPushedFcn = createCallbackFcn(app, @takebackgrounddataButtonPushed, true);
            app.takebackgrounddataButton.Text = 'take background data';
            app.collectingdatalasingonButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingonButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingonButtonPushed, true);
            app.collectingdatalasingonButton.Text = 'collecting data lasing on';
            app.collectingdatalasingoffButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingoffButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingoffButtonPushed, true);
            app.collectingdatalasingoffButton.Text = 'collecting data lasing off';
            app.PulsereconstructionButton = uibutton(app.pulsereconstructionTab, 'push');
            app.PulsereconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @PulsereconstructionButtonPushed, true);
            app.PulsereconstructionButton.Text = 'Pulse reconstruction ';
            app.feedbackonphaseCheckBox = uicheckbox(app.pulsereconstructionTab);
            app.feedbackonphaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.feedbackonphaseCheckBox.Text = 'feedback on phase';
            app.analysingimageButton = uibutton(app.pulsereconstructionTab, 'push');
            app.analysingimageButton.ButtonPushedFcn = createCallbackFcn(app, @analysingimageButtonPushed, true);
            app.analysingimageButton.Text = 'analysing image ';
            app.nshotsEditFieldLabel = uilabel(app.pulsereconstructionTab);
            app.nshotsEditFieldLabel.Text = 'n shots';
            app.nshotsEditField = uieditfield(app.pulsereconstructionTab, 'numeric');
            app.nshotsEditField.Value = 10;
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel = uilabel(app.pulsereconstructionTab);
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel.Text = 'for online measurment write the correct  range ';
            app.yrangeEditField = uieditfield(app.pulsereconstructionTab, 'text');
            app.yrangeEditField.Value = '1000';
            app.onlinemeasurmentTab = uitab(app.TabGroup);
            app.onlinemeasurmentTab.Title = 'on line measurment ';
            app.UIAxes_3 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes_3, 'image')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes_4, 'current and energy spread')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes_5, 'chirp calculation')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.STARTCheckBox = uicheckbox(app.onlinemeasurmentTab);
            app.STARTCheckBox.Text = 'START';
            app.STOPCheckBox = uicheckbox(app.onlinemeasurmentTab);
            app.STOPCheckBox.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end