classdef app4a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LongitudinalphasespaceandpulsereconstructionLPSPRToolUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TtimeandEnergycollibrationTab   matlab.ui.container.Tab
        onbeamButton                    matlab.ui.control.Button
        offbeamButton                   matlab.ui.control.Button
        phaseamplitudesetupButton       matlab.ui.control.Button
        pulsedurationPanel              matlab.ui.container.Panel
        temporalmeasurementButton       matlab.ui.control.Button
        ndphaseEditField                matlab.ui.control.NumericEditField
        ndphaseEditFieldLabel           matlab.ui.control.Label
        stphaseEditField                matlab.ui.control.NumericEditField
        stphaseEditFieldLabel           matlab.ui.control.Label
        TimecollibrationPanel           matlab.ui.container.Panel
        rightEditField                  matlab.ui.control.NumericEditField
        rightEditFieldLabel             matlab.ui.control.Label
        phaseSpinner                    matlab.ui.control.Spinner
        phaseSpinnerLabel               matlab.ui.control.Label
        leftEditField                   matlab.ui.control.NumericEditField
        leftEditFieldLabel              matlab.ui.control.Label
        DotimecollibrationButton        matlab.ui.control.Button
        EnergycollibrationPanel         matlab.ui.container.Panel
        downEditField                   matlab.ui.control.NumericEditField
        downEditFieldLabel              matlab.ui.control.Label
        currentSpinner                  matlab.ui.control.Spinner
        currentSpinnerLabel             matlab.ui.control.Label
        upEditField                     matlab.ui.control.NumericEditField
        upEditFieldLabel                matlab.ui.control.Label
        DoenergycollibrationButton      matlab.ui.control.Button
        DosinglemeasurementButton       matlab.ui.control.Button
        TDSsetupPanel                   matlab.ui.container.Panel
        yrangeEditField2                matlab.ui.control.EditField
        foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2  matlab.ui.control.Label
        StartCheckBox_2                 matlab.ui.control.CheckBox
        UIAxes_2                        matlab.ui.control.UIAxes
        pulsereconstructionTab          matlab.ui.container.Tab
        yrangeEditField                 matlab.ui.control.EditField
        foronlinemeasurmentwritethecorrectrangeEditFieldLabel  matlab.ui.control.Label
        nshotsEditField                 matlab.ui.control.NumericEditField
        nshotsEditFieldLabel            matlab.ui.control.Label
        analysingimageButton            matlab.ui.control.Button
        PulsereconstructionButton       matlab.ui.control.Button
        collectingdatalasingoffButton   matlab.ui.control.Button
        collectingdatalasingonButton    matlab.ui.control.Button
        takebackgrounddataButton        matlab.ui.control.Button
        StartCheckBox                   matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        onlinemeasurementTab            matlab.ui.container.Tab
        lasingoffdataButton             matlab.ui.control.Button
        STARTButton                     matlab.ui.control.StateButton
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        settingTab                      matlab.ui.container.Tab
        collectingdatainhdf5fileButton  matlab.ui.control.Button
        selectdiffrentenergycollibrationfileButton  matlab.ui.control.Button
        selectdiffrenttimecollibrationfileButton  matlab.ui.control.Button
        nshotsEditField_2               matlab.ui.control.NumericEditField
        nshotsEditFieldLabel_2          matlab.ui.control.Label
    end

 % select cameras

properties (Access = public)
    rep_rate  =10;
    num_bgr=10;
    addr_cam        = 'FLASH.DIAG/CAMERA/OTR9FL2XTDS/';
    addr_charge='FLASH.DIAG/TOROID/7FL2XTDS/CHARGE.FLASH2';
    addr_TDSphase='FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE';
    add_COFM='FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.CM'
    add2='FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.MEAN';
    add_phase='FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE'
    addr_dipol='FLASH.MAGNETS/MAGNET.ML/D4FL2XTDS/CURRENT.SP';
    color_map='turbo'
end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.phaseSpinner.Value=getfield(doocsread(app.addr_TDSphase) , 'data')
            app.rightEditField.Value=app.phaseSpinner.Value+2
            app.leftEditField.Value=app.phaseSpinner.Value-2
            %%
            app.currentSpinner.Value=getfield(doocsread(app.addr_dipol) , 'data')
            app.upEditField.Value=app.currentSpinner.Value+2
            app.downEditField.Value=app.currentSpinner.Value-2

            num_shot=app.nshotsEditField.Value;

%             while true
%                 if app.feedbackCheckBox.Value
%                 
%                 pause(2)
%                 end
%             end 
        end

        % Callback function: StartCheckBox_2, UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            % loop
            % here we just show the image on the screen
            flag=true

            jj=0
            while flag
                if app.StartCheckBox_2.Value
                    flag=true
                else
                    flag=false
                end
                jj=jj+1
                a=str2num(app.yrangeEditField2.Value);
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;

                I= imagesc(app.UIAxes_2,hlc_clean_image(img_sig(a:1:end,:,jj) ));
                colormap(app.UIAxes_2,app.color_map)
                pause(2/app.rep_rate)
            end
        end

        % Button pushed function: onbeamButton
        function onbeamButtonPushed(app, event)
            tem=doocswrite('FLASH.DIAG/TIMINGINFO/FLFXTDS/ON_BEAM', 1)
        end

        % Button pushed function: phaseamplitudesetupButton
        function phaseamplitudesetupButtonPushed(app, event)
            phase_ampSetupTDSFAST()
        end

        % Button pushed function: offbeamButton
        function offbeamButtonPushed(app, event)
            tem=doocswrite('FLASH.DIAG/TIMINGINFO/FLFXTDS/ON_BEAM', 0)
        end

        % Button pushed function: DotimecollibrationButton
        function DotimecollibrationButtonPushed(app, event)
            a=app.leftEditField.Value;
            b=app.rightEditField.Value;
            ylimit=app.yrangeEditField2.Value;
            nshot=app.nshotsEditField_2.Value;
            get_timecolibration(a, b, nshot, ylimit)
            
        end

        % Button pushed function: DoenergycollibrationButton
        function DoenergycollibrationButtonPushed(app, event)
            a=app.upEditField.Value
            b=app.downEditField.Value  
            nshot=app.nshotsEditField_2.Value;
            ylimit=str2num(app.yrangeEditField2.Value);
            get_energy_collibration(a, b, nshot)
           
        end

        % Button pushed function: DosinglemeasurementButton
        function DosinglemeasurementButtonPushed(app, event)
            % here we get one screan shot and analysis it, 
            % remmebr single shot 
            % read
            ddd_read     = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
            img(:,:)     = ddd_read.data.val_val;

            ts=ddd_read.timestamp;
            charge=doocsread(app.addr_charge);
            % compared it with last measurement

            save('imag_shot.mat', 'img','ts', 'charge');
            display(' - image data taken')
            a=str2num(app.yrangeEditField2.Value);
            output_filename=analysing_image('imag_shot.mat', a)
            display(' - data saved');
            Title='LPS analysis'
            text='NONE'
            figure(10012)
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,output_filename, Title, text ));

        end

        % Button pushed function: temporalmeasurementButton
        function temporalmeasurementButtonPushed(app, event)
             firstphase=app.stphaseEditField.Value;
            secondphase=app.ndphaseEditField.Value;
            nshot=app.nshotsEditField_2.Value;
            ylimit=app.yrangeEditField2.Value;
            output_filename=get_time_duration(firstphase, secondphase, ylimit, nshot);
            display(' - data saved');
            Title='temporal bunch measurement'
            text='NONE'
            figure(122321)
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,output_filename, Title, text ));


        end

        % Callback function: StartCheckBox, UIAxes
        function UIAxesButtonDown(app, event)
            % loop
            % here we just show the image on the screen
            if app.StartCheckBox.Value
                    flag=true;
            else flag=false;
            end

%             if app.feedbackonphaseCheckBox.Value
%                 
%                 x1=getfield(doocsread(app.add_COFM), 'data')
%                 phase_XTDS=getfield(doocsread(app.add_phase), 'data');
%                 tem=doocswrite(app.add_phase, phase_XTDS-0.5)
%                 x2=getfield(doocsread(app.add_COFM), 'data')
%                 if x2-x1 >0
%                     phase_feedback=1 ;% or -1
%                 elseif x2-x1 <0
%                     phase_feedback=-1;
%                 end 
%             end 
            jj=0
 
            while app.StartCheckBox.Value
%                 if app.StartCheckBox.Value
%                     flag=app.StartCheckBox.Value
%                 else
%                     flag=false
%                 end

                jj=jj+1

                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;
    
                a=str2num(app.yrangeEditField.Value);
                I= imagesc(app.UIAxes,hlc_clean_image(img_sig(a:1:end,:,jj)));
                colormap(app.UIAxes,app.color_map)
                if mod(jj,5)==0
%                     if app.feedbackonphaseCheckBox.Value
%                         x=getfield(doocsread(app.add_COFM), 'data')
%                         if mean(x) > 8
%                             phase_XTDS=getfield(doocsread(app.add_phase), 'data');
%                             tem=doocswrite(app.add_phase, phase_XTDS-phase_feedback)
%                         end
%                         if mean(x) < 5
%                             phase_XTDS=getfield(doocsread(app.add_phase), 'data');
%                             tem=doocswrite(app.add_phase, phase_XTDS+phase_feedback)
%                         end
%                     end
                end
                pause(1/app.rep_rate)

            end
        end

        % Button pushed function: collectingdatalasingoffButton
        function collectingdatalasingoffButtonPushed(app, event)
            % collecting image shot Lasing off
            delete imag_shots_lasing_off.mat
            num_shot=app.nshotsEditField.Value;
       % take bgr
            for jj = 1:num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                
                ts(jj)=ddd_read.timestamp;
                charge(jj)=doocsread(app.addr_charge);
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_off.mat', 'img_mean', 'img','ts', 'charge');
            display(' - image data taken')

        end

        % Button pushed function: takebackgrounddataButton
        function takebackgrounddataButtonPushed(app, event)
            % collecting back ground image
            delete background_img.mat
            
        
            % take bgr
            for jj = 1:app.num_bgr

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);

                img_bgr(:,:,jj)     = ddd_read.data.val_val;
                t(jj)=ddd_read.timestamp;
                
                % compared it with last measurement
                 if jj > 1
                     while t(jj) == t(jj-1)
                         ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                         img_bgr(:,:,jj)       = ddd_read.data.val_val;
                         display([' - (', num2str(jj), ') same data ... wait ...']);
                         pause(1/app.rep_rate)
                     end
                 end

                pause(1./app.rep_rate)

            end

            % compute mean()
            if app.num_bgr == 1
                img_bgr_mean = squeeze(img_bgr);
            else
                img_bgr_mean = squeeze(mean(img_bgr, 3));
            end
            %Saved back ground file

            save('background_img.mat', 'img_bgr_mean');

            display(' - background data taken')

        end

        % Button pushed function: collectingdatalasingonButton
        function collectingdatalasingonButtonPushed(app, event)
            
               % collecting image shot lasing on 
            delete imag_shots_lasing_on.mat
           num_shot=app.nshotsEditField.Value;
            % take bgr
            for jj = 1:num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                charge(jj)=doocsread(app.addr_charge);
                ts(jj)=ddd_read.timestamp;
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_on.mat', 'img_mean', 'img', 'ts', 'charge');
            display(' - image data taken')

        end

        % Button pushed function: analysingimageButton
        function analysingimageButtonPushed(app, event)
            %address=~/
            %cd %(address)
            filename=uigetfile;
            assignin('base', 'filename',  filename)
            a=str2num(app.yrangeEditField.Value);
            output_filename=analysing_image(filename, a)

            display(' - data saved');
            Title='LPS analysis'
            text='NONE'
            figure(10012)
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,output_filename, Title, text ));

        end

        % Button pushed function: PulsereconstructionButton
        function PulsereconstructionButtonPushed(app, event)
            close all
            addpath('/System/Volumes/Data/home/ttflinac/user/mirian/FL2_funs')
            a=str2num(app.yrangeEditField.Value)
            filename= Pulse_reconstruction(a)
            figure(1044)
            Title='Pulse Recunstruction'
            text='NONE'
            uicontrol('String', 'Print', 'Callback', @(btn,~)printinlogbook(btn,filename, Title, text ));
            figure(1)
            Title='Pulse Recunstruction'
            text='NONE'
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,filename, Title, text ));
        end

        % Value changing function: phaseSpinner
        function phaseSpinnerChangingValue(app, event)
            app.phaseSpinner.Value=getfield(doocsread(app.addr_TDSphase) , 'data')
            tem=doocswrite(app.add_phase, app.phaseSpinner.Value+1);
            app.rightEditField.Value=app.phaseSpinner.Value+2;
            app.leftEditField.Value=app.phaseSpinner.Value-2     ;
              %tem=doocswrite(app.add_phase, app.phaseSpinner.Value);
            %display(app.phaseSpinner.Value)
        end

        % Value changed function: currentSpinner
        function currentSpinnerValueChanged(app, event)
            %value = app.currentSpinner.Value;
            %tem=doocswrite(app.add_dipol, app.currentSpinner.Value)
            app.upEditField.Value=app.currentSpinner.Value-1;
            app.downEditField.Value=app.currentSpinner.Value+1;
            tem=doocswrite(app.addr_dipol, app.currentSpinner.Value);

        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            value = app.STARTButton.Value
            
             if value==1
                 app.STARTButton.Text='STOP'
             else
                 app.STARTButton.Text='START'
            flag=true
            end
            load('/home/ttflinac/user/mflorian/PolariX/FL2/time_calibration/time_calib_9FL2XTDS.mat', 'timecal_fspixel', ...
                'timecal_fspixel_err', 'timecal_streak', 'time_res');
            load('/home/ttflinac/user/mflorian/PolariX/FL2/energy_calibration/erg_calib_9FL2XTDS.mat', 'ergcal', 'ergcal_err')
           load('lasing_off_data.mat')
            %evalin('base','current_lasing_off')
%             evalin('base','slice_energy_spread_off')
%             evalin('base','cent_energy_off')
%             evalin('base','mass_center_off')
%             evalin('base','time_pos_good_off')
% 
fontSize=18;
            
w=gausswin(10,2.5);
 w=w/sum(  w          );
            display('data is loaded');


            calib_x             = timecal_fspixel;
            calib_y             = ergcal;
            timeres_calib       = time_res;
            % erg_axis            = ergcal * ((1:length_y)-length_y/2);
            % time_axis           = timecal_fspixel * ((1:length_x)-length_x/2);

            a=str2num(app.yrangeEditField.Value);
            ddd_read   = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
            img_sig(:,:)     = ddd_read.data.val_val;
            
            [length_y, length_x]=size(img_sig)
            y_axis=ergcal * ((1:length_y)-length_y/2);
            x_axis  = timecal_fspixel * ((1:length_x)-length_x/2);
            j=0
            while app.STARTButton.Value
               
               % tic
               a=str2num(app.yrangeEditField.Value);
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                charge_7FL2XTDS= getfield(doocsread(app.addr_charge), 'data');
                img_sig(:,:)     = ddd_read.data.val_val;
                a=str2num(app.yrangeEditField.Value);
                %[length_y, length_x]=size(img_sig)
                %y_axis=ergcal * ((1:length_y)-length_y/2);
                %x_axis  = timecal_fspixel * ((1:length_x)-length_x/2);
                imag=hlc_clean_image( squeeze(img_sig(a:end,:)));


                % time
                tmp_profile         = mean(imag);
                [x_com, x_var, x_fwhm, x_axis, x_profile]  = get_profile_stats(tmp_profile, timecal_fspixel);

                % erg
                tmp_profile         = mean(imag, 2);
                %     tmp_profile         = medfilt1(tmp_profile, 1);
                [y_com, y_var, y_fwhm, y_axis, y_profile]  = get_profile_stats(tmp_profile', ergcal);


                %%
                erg_pos_good =  find(y_profile(end,:)>0); erg_pos_good = min(erg_pos_good)-10:max(erg_pos_good)+10;
                time_pos_good = find(x_profile(end,:)>0); time_pos_good = min(time_pos_good)-10:max(time_pos_good)+10;

                cent_energy=zeros(length_x);
                slice_energy_spread=zeros(length_x);

                I= imagesc(app.UIAxes1, x_axis(time_pos_good ), y_axis(erg_pos_good),imag(erg_pos_good,time_pos_good));
                I2=plot(app.UIAxes2, y_profile(erg_pos_good),y_axis(erg_pos_good),  'LineWidth', 3, 'Color','r');
                I3=  plot(app.UIAxes3, x_axis(time_pos_good ), smoothdata(1e-3*charge_7FL2XTDS*1e-9*1e15 * x_profile(time_pos_good )), 'LineWidth' ,3, 'Color','r');
                legend([I3], {['rms=', num2str(x_var),'fs', 10, ...
                    'FWHM= ', num2str(x_fwhm), 'fs'] }, 'FontSize',18)

                parfor n=min(time_pos_good):max(time_pos_good)


                    Ans=hlc_fit_gaussian(erg_pos_good, smoothdata(imag(erg_pos_good,n)) );
                    %Ans=[baseline, height,mu, sigma]
                    if isnan(Ans(4))
                    else

                        slice_energy_spread(n)=Ans(4)*calib_y;
                        cent_energy(n)=Ans(3)*calib_y;
%                          [y_com, y_var, y_fwhm, y_axis, y_profile]  = get_profile_stats(smoothdata(imag(erg_pos_good,n)), ergcal);
%                          slice_energy_spread(n)=y_var;
%                         cent_energy_cr(n)=y_com;
                    end
                     if slice_energy_spread(n)> 100
                        slice_energy_spread(n)=0
                    end
                    %end


                end


                 slice_energy_spread=filter(w,1,slice_energy_spread);
                        cent_energy=filter(w,1,cent_energy);

                p1 = plot( app.UIAxes4, ...
                    x_axis(time_pos_good)-mean(x_axis(time_pos_good),'omitnan'),...
                    smoothdata(cent_energy(time_pos_good)'-mean(cent_energy(time_pos_good))),'LineWidth' ,3, 'Color','b');


                p2 = plot( app.UIAxes5,...
                    x_axis(time_pos_good)-mean(x_axis(time_pos_good),'omitnan'),...
                    smoothdata(slice_energy_spread(time_pos_good)),'LineWidth' ,2, 'Color','m');
                j=j+1
               pause(0.001)
                
                %toc
                %print(j)

                %%%%%%%% On-line pulse reconstruction : 


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               
                %if app.PulseReconstructionButton
                    pp=figure(123456)
                    set(pp,'position', [100,100, 800, 500])

                    %%%%%%
                    current_lasing_on=smoothdata(1e-3*charge_7FL2XTDS*1e-9*1e15 * x_profile);
                    mass_center_on=find(current_lasing_on==max(current_lasing_on));
                    mass_center_on=0;
                    mass_center_off=0;

                    %%%%%%%%%%%
                    %power= cent_energy(time_pos_good-mass_center_on).* current_lasing_on(time_pos_good-mass_center_on) - ...
                    %   cent_energy_off(time_pos_good-mass_center_off).* current_lasing_off(time_pos_good-mass_center_off);

                    power= cent_energy(time_pos_good).* current_lasing_off(time_pos_good) - ...
                       cent_energy_off(time_pos_good).* current_lasing_off(time_pos_good);


                    subplot(1,2,1)
                    plot(x_axis(time_pos_good)-mean(x_axis(time_pos_good),'omitnan'), smoothdata(power(:)), '-k', 'LineWidth',1.5 )
                    hold on
                    %plot(x_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), power2(:), '-b', 'LineWidth',1.5 )
                    xlabel('time t /fs', 'FontSize', fontSize)
                    ylabel('peak power (GW)', 'FontSize', fontSize)
                    legend('Using energy deviation')
                    set(gca, 'FontSize', fontSize,'YDir', 'normal')
                    if mod(j,20)==0
                        figure(123456)
                        hold off
                    end 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    
                    power= (slice_energy_spread(time_pos_good).^2 - ...
                        slice_energy_spread_off(time_pos_good).^2).* (current_lasing_off(time_pos_good).^(2/3));

                  %  power2=(cent_energy_cr(time_pos_good-mass_center_on)- ...
                  %      slice_energy_spread_off(time_pos_good-mass_center_off)).* imass_center_off(time_pos_good-mass_center_off);

                       subplot(1,2,2)
                    plot(x_axis(time_pos_good)-mean(x_axis(time_pos_good),'omitnan'), smoothdata(power(:)), '-k', 'LineWidth',1 )
                    hold on
                    %plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), power2(:), '-b', 'LineWidth',3 )
                    xlabel('time t /fs', 'FontSize', fontSize)
                    ylabel('peak power (a.u)', 'FontSize', fontSize)
                    legend('Using energy spread')
                    set(gca, 'FontSize', fontSize,'YDir', 'normal')

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if mod(j,20)==0
                        figure(123456)
                        hold off
                    end 
                pause(0.001)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                %end 


            end
        end

        % Button pushed function: lasingoffdataButton
        function lasingoffdataButtonPushed(app, event)
            %%prepering lasing off data file for pulse reconstruction

            filename=uigetfile;
            assignin('base', 'filename',  filename)
            a=str2num(app.yrangeEditField.Value);
                        output_lasing_off=analysing_image(filename, a)

           % assignin('base', 'filename',  output_lasing_off)

            lasing_off=load(output_lasing_off);
            current_lasing_off=smoothdata(lasing_off.current);
            mass_center_off=find(current_lasing_off==max(current_lasing_off));

            cent_energy_off=mean(lasing_off.cent_energy_cr,1);

            slice_energy_spread_off=mean(lasing_off.slice_enrgy_spread,1);

            %current_lasing_off=mean(lasing_off.current);
            time_pos_good_off=lasing_off.time_pos_good

            assignin('base', 'current_lasing_off', current_lasing_off) ;
            assignin('base', 'slice_energy_spread_off', slice_energy_spread_off) ;
            assignin('base', 'cent_energy_off', cent_energy_off) ;
            assignin('base', 'mass_center_off', mass_center_off) ;
            assignin('base', 'time_pos_good_off', lasing_off.time_pos_good) ;


            save('lasing_off_data.mat', 'current_lasing_off','slice_energy_spread_off','cent_energy_off','mass_center_off','time_pos_good_off')
            display('data is ready      ')


        end

        % Button pushed function: selectdiffrenttimecollibrationfileButton
        function selectdiffrenttimecollibrationfileButtonPushed(app, event)
            selectdiffrenttimecollibrationfile()
        end

        % Button pushed function: 
        % selectdiffrentenergycollibrationfileButton
        function selectdiffrentenergycollibrationfileButtonPushed(app, event)
            selectdiffrentenergycollibrationfile()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LongitudinalphasespaceandpulsereconstructionLPSPRToolUIFigure = uifigure('Visible', 'off');
            app.LongitudinalphasespaceandpulsereconstructionLPSPRToolUIFigure.Name = 'Longitudinal phase space and pulse reconstruction (LPS&PR) Tool';
            app.TabGroup = uitabgroup(app.LongitudinalphasespaceandpulsereconstructionLPSPRToolUIFigure);
            app.TtimeandEnergycollibrationTab = uitab(app.TabGroup);
            app.TtimeandEnergycollibrationTab.Title = 'Ttime and Energy collibration ';
            app.UIAxes_2 = uiaxes(app.TtimeandEnergycollibrationTab);
            title(app.UIAxes_2, 'image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.TDSsetupPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.TDSsetupPanel.Title = 'TDS setup';
            app.StartCheckBox_2 = uicheckbox(app.TDSsetupPanel);
            app.StartCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.StartCheckBox_2.Text = 'Start ';
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2 = uilabel(app.TDSsetupPanel);
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2.Text = 'for online measurment write the correct  range ';
            app.yrangeEditField2 = uieditfield(app.TDSsetupPanel, 'text');
            app.yrangeEditField2.Value = '1';
            app.DosinglemeasurementButton = uibutton(app.TtimeandEnergycollibrationTab, 'push');
            app.DosinglemeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @DosinglemeasurementButtonPushed, true);
            app.DosinglemeasurementButton.Text = 'Do single measurement';
            app.EnergycollibrationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.EnergycollibrationPanel.Title = 'Energy collibration';
            app.DoenergycollibrationButton = uibutton(app.EnergycollibrationPanel, 'push');
            app.DoenergycollibrationButton.ButtonPushedFcn = createCallbackFcn(app, @DoenergycollibrationButtonPushed, true);
            app.DoenergycollibrationButton.Text = 'Do energy collibration';
            app.upEditFieldLabel = uilabel(app.EnergycollibrationPanel);
            app.upEditFieldLabel.Text = 'up';
            app.upEditField = uieditfield(app.EnergycollibrationPanel, 'numeric');
            app.currentSpinnerLabel = uilabel(app.EnergycollibrationPanel);
            app.currentSpinnerLabel.Text = 'current';
            app.currentSpinner = uispinner(app.EnergycollibrationPanel);
            app.currentSpinner.ValueChangedFcn = createCallbackFcn(app, @currentSpinnerValueChanged, true);
            app.downEditFieldLabel = uilabel(app.EnergycollibrationPanel);
            app.downEditFieldLabel.Text = 'down';
            app.downEditField = uieditfield(app.EnergycollibrationPanel, 'numeric');
            app.TimecollibrationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.TimecollibrationPanel.Title = 'Time collibration';
            app.DotimecollibrationButton = uibutton(app.TimecollibrationPanel, 'push');
            app.DotimecollibrationButton.ButtonPushedFcn = createCallbackFcn(app, @DotimecollibrationButtonPushed, true);
            app.DotimecollibrationButton.Text = 'Do time collibration';
            app.leftEditFieldLabel = uilabel(app.TimecollibrationPanel);
            app.leftEditFieldLabel.Text = 'left';
            app.leftEditField = uieditfield(app.TimecollibrationPanel, 'numeric');
            app.phaseSpinnerLabel = uilabel(app.TimecollibrationPanel);
            app.phaseSpinnerLabel.Text = 'phase';
            app.phaseSpinner = uispinner(app.TimecollibrationPanel);
            app.phaseSpinner.ValueChangingFcn = createCallbackFcn(app, @phaseSpinnerChangingValue, true);
            app.phaseSpinner.Value = 120;
            app.rightEditFieldLabel = uilabel(app.TimecollibrationPanel);
            app.rightEditFieldLabel.Text = 'right';
            app.rightEditField = uieditfield(app.TimecollibrationPanel, 'numeric');
            app.pulsedurationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.pulsedurationPanel.Title = 'pulse duration ';
            app.stphaseEditFieldLabel = uilabel(app.pulsedurationPanel);
            app.stphaseEditFieldLabel.Text = '1st phase';
            app.stphaseEditField = uieditfield(app.pulsedurationPanel, 'numeric');
            app.ndphaseEditFieldLabel = uilabel(app.pulsedurationPanel);
            app.ndphaseEditFieldLabel.Text = '2nd phase';
            app.ndphaseEditField = uieditfield(app.pulsedurationPanel, 'numeric');
            app.temporalmeasurementButton = uibutton(app.pulsedurationPanel, 'push');
            app.temporalmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @temporalmeasurementButtonPushed, true);
            app.temporalmeasurementButton.Text = 'temporal measurement';
            app.phaseamplitudesetupButton = uibutton(app.TtimeandEnergycollibrationTab, 'push');
            app.phaseamplitudesetupButton.ButtonPushedFcn = createCallbackFcn(app, @phaseamplitudesetupButtonPushed, true);
            app.phaseamplitudesetupButton.Text = 'phase&amplitude setup';
            app.offbeamButton = uibutton(app.TtimeandEnergycollibrationTab, 'push');
            app.offbeamButton.ButtonPushedFcn = createCallbackFcn(app, @offbeamButtonPushed, true);
            app.offbeamButton.Text = 'off beam';
            app.onbeamButton = uibutton(app.TtimeandEnergycollibrationTab, 'push');
            app.onbeamButton.ButtonPushedFcn = createCallbackFcn(app, @onbeamButtonPushed, true);
            app.onbeamButton.Text = 'on beam';
            app.pulsereconstructionTab = uitab(app.TabGroup);
            app.pulsereconstructionTab.Title = 'pulse reconstruction';
            app.UIAxes = uiaxes(app.pulsereconstructionTab);
            title(app.UIAxes, 'image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox = uicheckbox(app.pulsereconstructionTab);
            app.StartCheckBox.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox.Text = 'Start ';
            app.takebackgrounddataButton = uibutton(app.pulsereconstructionTab, 'push');
            app.takebackgrounddataButton.ButtonPushedFcn = createCallbackFcn(app, @takebackgrounddataButtonPushed, true);
            app.takebackgrounddataButton.Text = 'take background data';
            app.collectingdatalasingonButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingonButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingonButtonPushed, true);
            app.collectingdatalasingonButton.Text = 'collecting data lasing on';
            app.collectingdatalasingoffButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingoffButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingoffButtonPushed, true);
            app.collectingdatalasingoffButton.Text = 'collecting data lasing off';
            app.PulsereconstructionButton = uibutton(app.pulsereconstructionTab, 'push');
            app.PulsereconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @PulsereconstructionButtonPushed, true);
            app.PulsereconstructionButton.Text = 'Pulse reconstruction ';
            app.analysingimageButton = uibutton(app.pulsereconstructionTab, 'push');
            app.analysingimageButton.ButtonPushedFcn = createCallbackFcn(app, @analysingimageButtonPushed, true);
            app.analysingimageButton.Text = 'analysing image ';
            app.nshotsEditFieldLabel = uilabel(app.pulsereconstructionTab);
            app.nshotsEditFieldLabel.Text = 'n shots';
            app.nshotsEditField = uieditfield(app.pulsereconstructionTab, 'numeric');
            app.nshotsEditField.Value = 10;
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel = uilabel(app.pulsereconstructionTab);
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel.Text = 'for online measurment write the correct  range ';
            app.yrangeEditField = uieditfield(app.pulsereconstructionTab, 'text');
            app.yrangeEditField.Value = '1';
            app.onlinemeasurementTab = uitab(app.TabGroup);
            app.onlinemeasurementTab.Title = 'on line measurement ';
            app.UIAxes5 = uiaxes(app.onlinemeasurementTab);
            title(app.UIAxes5, 'energy spread ')
            xlabel(app.UIAxes5, 't[fs]')
            ylabel(app.UIAxes5, 'energy spread [MeV]')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.onlinemeasurementTab);
            title(app.UIAxes4, 'chirp calculation')
            xlabel(app.UIAxes4, 't[fs]')
            ylabel(app.UIAxes4, '\Delta energy [MeV]')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.onlinemeasurementTab);
            title(app.UIAxes3, 'current')
            xlabel(app.UIAxes3, 't[fs]')
            ylabel(app.UIAxes3, 'current [KA]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.onlinemeasurementTab);
            title(app.UIAxes2, 'energy projection')
            xlabel(app.UIAxes2, 'N')
            ylabel(app.UIAxes2, 'energy [MeV]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XDir = 'reverse';
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.YAxisLocation = 'right';
            app.UIAxes1 = uiaxes(app.onlinemeasurementTab);
            title(app.UIAxes1, 'energy and time space')
            xlabel(app.UIAxes1, 'time(fs)')
            ylabel(app.UIAxes1, 'energy (MeV)')
            zlabel(app.UIAxes1, 'Z')
            app.STARTButton = uibutton(app.onlinemeasurementTab, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.lasingoffdataButton = uibutton(app.onlinemeasurementTab, 'push');
            app.lasingoffdataButton.ButtonPushedFcn = createCallbackFcn(app, @lasingoffdataButtonPushed, true);
            app.lasingoffdataButton.Text = 'lasing off data';
            app.settingTab = uitab(app.TabGroup);
            app.settingTab.Title = 'setting';
            app.nshotsEditFieldLabel_2 = uilabel(app.settingTab);
            app.nshotsEditFieldLabel_2.Text = 'n shots';
            app.nshotsEditField_2 = uieditfield(app.settingTab, 'numeric');
            app.nshotsEditField_2.Value = 10;
            app.selectdiffrenttimecollibrationfileButton = uibutton(app.settingTab, 'push');
            app.selectdiffrenttimecollibrationfileButton.ButtonPushedFcn = createCallbackFcn(app, @selectdiffrenttimecollibrationfileButtonPushed, true);
            app.selectdiffrenttimecollibrationfileButton.Text = 'select diffrent  time collibration file ';
            app.selectdiffrentenergycollibrationfileButton = uibutton(app.settingTab, 'push');
            app.selectdiffrentenergycollibrationfileButton.ButtonPushedFcn = createCallbackFcn(app, @selectdiffrentenergycollibrationfileButtonPushed, true);
            app.selectdiffrentenergycollibrationfileButton.Text = 'select diffrent  energy collibration file ';
            app.collectingdatainhdf5fileButton = uibutton(app.settingTab, 'push');
            app.collectingdatainhdf5fileButton.Text = 'collecting data in hdf5 file ';
            % Show the figure after all components are created
            app.LongitudinalphasespaceandpulsereconstructionLPSPRToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LongitudinalphasespaceandpulsereconstructionLPSPRToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LongitudinalphasespaceandpulsereconstructionLPSPRToolUIFigure)
        end
    end
end