classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TtimeandEnergycollibrationTab  matlab.ui.container.Tab
        pulsedurationPanel             matlab.ui.container.Panel
        measurmentButton               matlab.ui.control.Button
        ndphaseEditField               matlab.ui.control.NumericEditField
        ndphaseEditFieldLabel          matlab.ui.control.Label
        stphaseEditField               matlab.ui.control.NumericEditField
        stphaseEditFieldLabel          matlab.ui.control.Label
        yrangeEditField2               matlab.ui.control.EditField
        foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2  matlab.ui.control.Label
        StartCheckBox_2                matlab.ui.control.CheckBox
        TimecollibrationPanel          matlab.ui.container.Panel
        rightEditField                 matlab.ui.control.NumericEditField
        rightEditFieldLabel            matlab.ui.control.Label
        phaseSpinner                   matlab.ui.control.Spinner
        phaseSpinnerLabel              matlab.ui.control.Label
        leftEditField                  matlab.ui.control.NumericEditField
        leftEditFieldLabel             matlab.ui.control.Label
        DotimecollibrationButton       matlab.ui.control.Button
        EnergycollibrationPanel        matlab.ui.container.Panel
        downEditField                  matlab.ui.control.NumericEditField
        downEditFieldLabel             matlab.ui.control.Label
        currentSpinner                 matlab.ui.control.Spinner
        currentSpinnerLabel            matlab.ui.control.Label
        upEditField                    matlab.ui.control.NumericEditField
        upEditFieldLabel               matlab.ui.control.Label
        DoenergycollibrationButton     matlab.ui.control.Button
        DosinglemeasurmentButton       matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        pulsereconstructionTab         matlab.ui.container.Tab
        yrangeEditField                matlab.ui.control.EditField
        foronlinemeasurmentwritethecorrectrangeEditFieldLabel  matlab.ui.control.Label
        nshotsEditField                matlab.ui.control.NumericEditField
        nshotsEditFieldLabel           matlab.ui.control.Label
        analysingimageButton           matlab.ui.control.Button
        feedbackonphaseCheckBox        matlab.ui.control.CheckBox
        PulsereconstructionButton      matlab.ui.control.Button
        collectingdatalasingoffButton  matlab.ui.control.Button
        collectingdatalasingonButton   matlab.ui.control.Button
        takebackgrounddataButton       matlab.ui.control.Button
        StartCheckBox                  matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        onlinemeasurmentTab            matlab.ui.container.Tab
        logbookButton                  matlab.ui.control.Button
        STARTButton                    matlab.ui.control.StateButton
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
    end

 % select cameras .

properties (Access = public)
    rep_rate  =10;
    num_bgr=10;
    addr_cam        = 'FLASH.DIAG/CAMERA/OTR9FL2XTDS/';
    addr_charge='FLASH.DIAG/TOROID/7FL2XTDS/CHARGE.FLASH2';
    addr_TDSphase='FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE';
    add_COFM='FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.CM'
    add2='FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.MEAN';
    add_phase='FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE'
    addr_dipol='TTF2.MAGNETS/DIPOLE/D4FL2XTDS/PS'
end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.phaseSpinner.Value=getfield(doocsread(app.addr_TDSphase) , 'data')
            app.rightEditField.Value=app.phaseSpinner.Value+2
            app.leftEditField.Value=app.phaseSpinner.Value-2
            %%
            app.currentSpinner.Value=getfield(doocsread(app.addr_dipol) , 'data')
            app.upEditField.Value=app.currentSpinner.Value+2
            app.downEditField.Value=app.currentSpinner.Value-2

            num_shot=str2num(app.nshotsEditField.Value);

        end

        % Callback function: StartCheckBox, UIAxes, feedbackonphaseCheckBox
        function UIAxesButtonDown(app, event)
            % loop
            % here we just show the image on the screen
            if app.StartCheckBox.Value
                    flag=true;
            else flag=false;
            end

            if app.feedbackonphaseCheckBox.Value
                
                x1=getfield(doocsread(app.add_COFM), 'data')
                phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                tem=doocswrite(app.add_phase, phase_XTDS-0.5)
                x2=getfield(doocsread(app.add_COFM), 'data')
                if x2-x1 >0
                    phase_feedback=1 ;% or -1
                elseif x2-x1 <0
                    phase_feedback=-1;
                end 
            end 
            jj=0
 
            while app.StartCheckBox.Value
%                 if app.StartCheckBox.Value
%                     flag=app.StartCheckBox.Value
%                 else
%                     flag=false
%                 end

                jj=jj+1

                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;
    
                a=str2num(app.yrangeEditField.Value);
                I= imagesc(app.UIAxes,img_sig(a:1:end,:,jj));
                if mod(jj,5)==0
                    if app.feedbackonphaseCheckBox.Value
                        x=getfield(doocsread(app.add_COFM), 'data')
                        if mean(x) > 8
                            phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                            tem=doocswrite(app.add_phase, phase_XTDS-phase_feedback)
                        end
                        if mean(x) < 5
                            phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                            tem=doocswrite(app.add_phase, phase_XTDS+phase_feedback)
                        end
                    end
                end
                pause(2/app.rep_rate)

            end
        end

        % Button pushed function: takebackgrounddataButton
        function takebackgrounddataButtonPushed(app, event)
            % collecting back ground image
            delete background_img.mat
            
        
            % take bgr
            for jj = 1:app.num_bgr

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);

                img_bgr(:,:,jj)     = ddd_read.data.val_val;
                t(jj)=ddd_read.timestamp;
                
                % compared it with last measurement
                 if jj > 1
                     while t(jj) == t(jj-1)
                         ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                         img_bgr(:,:,jj)       = ddd_read.data.val_val;
                         display([' - (', num2str(jj), ') same data ... wait ...']);
                         pause(1/app.rep_rate)
                     end
                 end

                pause(1./app.rep_rate)

            end

            % compute mean()
            if app.num_bgr == 1
                img_bgr_mean = squeeze(img_bgr);
            else
                img_bgr_mean = squeeze(mean(img_bgr, 3));
            end
            %Saved back ground file

            save('background_img.mat', 'img_bgr_mean');

            display(' - background data taken')

        end

        % Button pushed function: collectingdatalasingoffButton
        function collectingdatalasingoffButtonPushed(app, event)
            % collecting image shot Lasing off
            delete imag_shots_lasing_off.mat
            
       % take bgr
            for jj = 1:app.num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                
                ts(jj)=ddd_read.timestamp;
                charge(jj)=doocsread(app.addr_charge);
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_off.mat', 'img_mean', 'img','ts', 'charge');
            display(' - image data taken')

        end

        % Button pushed function: collectingdatalasingonButton
        function collectingdatalasingonButtonPushed(app, event)
            
               % collecting image shot lasing on 
            delete imag_shots_lasing_on.mat
          
            % take bgr
            for jj = 1:app.num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                charge(jj)=doocsread(app.addr_charge);
                ts(jj)=ddd_read.timestamp;
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_on.mat', 'img_mean', 'img', 'ts', 'charge');
            display(' - image data taken')

        end

        % Button pushed function: analysingimageButton
        function analysingimageButtonPushed(app, event)
            %address=~/
            %cd %(address)
            filename=uigetfile;
            assignin('base', 'filename',  filename)
            a=str2num(app.yrangeEditField.Value);
            output_filename=analysing_image(filename, a)

            display(' - data saved');
            Title='LPS analysis'
            text='NONE'
            figure(10012)
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,output_filename, Title, text ));

        end

        % Button pushed function: PulsereconstructionButton
        function PulsereconstructionButtonPushed(app, event)
            close all
            a=str2num(app.yrangeEditField.Value);
            filename= Pulse_reconstruction(a)
            figure(1044)
            Title='Pulse Recunstruction'
            text='NONE'
            uicontrol('String', 'Print', 'Callback', @(btn,~)printinlogbook(btn,filename, Title, text ));
            figure(1)
            Title='Pulse Recunstruction'
            text='NONE'
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,filename, Title, text ));
        end

        % Callback function: StartCheckBox_2, UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            % loop
            % here we just show the image on the screen
            flag=true

            jj=0
            while flag
                if app.StartCheckBox_2.Value
                    flag=true
                else
                    flag=false
                end
                jj=jj+1

                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;

                I= imagesc(app.UIAxes_2,img_sig(1:end,:,jj) ,'EdgeColor','none');
                pause(2/app.rep_rate)
            end
        end

        % Value changing function: phaseSpinner
        function phaseSpinnerValueChanging(app, event)
       
            app.rightEditField.Value=app.phaseSpinner.Value+2
            app.leftEditField.Value=app.phaseSpinner.Value-2     
            tem=doocswrite(app.add_phase, app.phaseSpinner.Value-1)
            display(app.phaseSpinner.Value)
        end

        % Value changed function: currentSpinner
        function currentSpinnerValueChanged(app, event)
            %value = app.currentSpinner.Value;
            %tem=doocswrite(app.add_dipol, app.currentSpinner.Value)
            app.upEditField.Value=app.currentSpinner.Value-1
            app.downEditField.Value=app.currentSpinner.Value+1

        end

        % Button pushed function: DotimecollibrationButton
        function DotimecollibrationButtonPushed(app, event)
            a=str2num(app.leftEditField.Value)
            b=str2num(app.rightEditField.Value)  
            num_sig=10
            ylimit=str2num(app.yrangeEditField2.Value);
            get_timecolibration(a, b, num_sig, ylimit)
            % Done should be test 
        end

        % Button pushed function: DoenergycollibrationButton
        function DoenergycollibrationButtonPushed(app, event)
            a=str2num(app.upEditField.Value)
            b=str2num(app.downEditField.Value)  
            num_sig=10
            ylimit=str2num(app.yrangeEditField2.Value);
            get_energy_colibration(a, b, num_sig)
            %  done , need to be tested
        end

        % Button pushed function: DosinglemeasurmentButton
        function DosinglemeasurmentButtonPushed(app, event)
            % here we get one screan shot and analysis it, 
            % remmebr single shot 
            % read
            ddd_read     = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
            img(:,:)     = ddd_read.data.val_val;

            ts=ddd_read.timestamp;
            charge=doocsread(app.addr_charge);
            % compared it with last measurement

            save('imag_shot.mat', 'img','ts', 'charge');
            display(' - image data taken')
            a=str2num(app.yrangeEditField2.Value);
            output_filename=analysing_image('imag_shot.mat', a)
            display(' - data saved');
            Title='LPS analysis'
            text='NONE'
            figure(10012)
            uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,output_filename, Title, text ));

        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            value = app.STARTButton.Value;
            while app.STARTButton.Value
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                % ccharge_7FL2XTDS=,
                img_sig(:,:)     = ddd_read.data.val_val;
                a=str2num(app.yrangeEditField.Value);
                length_y, length_x=sizeimg_sig
                %y_axis, y_axis= collibrataxis(length_y, length_x)
                imag=hlc_clean_image( squeeze(img_sig(a:end,:)))
                I= imagesc(app.UIAxes1, y, x,img );

                % time
                tmp_profile         = mean(imag);
                [x_com, x_var, x_fwhm, x_axis, x_profile]  = get_profile_stats(tmp_profile, timecal_fspixel);

                % erg
                tmp_profile         = mean(imag, 2);
                %     tmp_profile         = medfilt1(tmp_profile, 1);
                [y_com, y_var, y_fwhm, y_axis, y_profile]  = get_profile_stats(tmp_profile', ergcal);


                I2=plot(UIAxes1, y_axis, y_profilre, 'LineWdith', 3, 'Color','r')

                I3=  plot(UIAxes2, x_axis, 1e-3*charge_7FL2XTDS*1e-9*1e15 * x_profile, 'LineWdith', 3, 'Color','r')
                %%
                erg_pos_good =  find(y_profile(end,:)>0); erg_pos_good = min(erg_pos_good):max(erg_pos_good);
                time_pos_good = find(x_profile(end,:)>0); time_pos_good = min(time_pos_good):max(time_pos_good);

                cent_energy_cr=zeros(length_x);
                slice_enrgy_spread=zeros(length_x);

                parfor n=min(time_pos_good):max(time_pos_good)


                    Ans=hlc_fit_gaussian(erg_pos_good, smoothdata(imag(erg_pos_good,n)) );
                    %Ans=[baseline, height,mu, sigma]
                    if isnan(Ans(3))
                    else

                        slice_enrgy_spread(n)=Ans(4)*calib_y;
                        cent_energy_cr(n)=Ans(3)*calib_y;
                    end

                end


                p1 = plot( UIAxes4, ...
                    time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
                    smoothdata(cent_energy_cr(time_pos_good)));


                p2 = plot( UIAxes5,...
                    time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
                    slice_energy_spread(time_pos_good));

                %pause(2)

%uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,output_filename, Title, text ));

            end
        end

        % Button pushed function: logbookButton
        function logbookButtonPushed(app, event)
             text=['on line measurment']
%             A=getframe(gcf);
%             imwrite(A.cdata, 'temporly.png');
%             result_log=hlc_send_to_logbook('title', 'on line measurment', ...
%                 'text', text, 'image', 'temporly.png')
            imageData = screencapture('handle',app.UIFigure, 'target','temporly.jpg');
            result_log=hlc_send_to_logbook('title', 'on line measurment', ...
                'text', text, 'image', 'temporly.png')
        end

        % Button pushed function: measurmentButton
        function measurmentButtonPushed(app, event)
            firstphase=app.stphaseEditField.Value;
            secondphase=app.ndphaseEditField.Value;
            get_time_duration(firstphase, secondphase)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TtimeandEnergycollibrationTab = uitab(app.TabGroup);
            app.TtimeandEnergycollibrationTab.Title = 'Ttime and Energy collibration ';
            app.UIAxes_2 = uiaxes(app.TtimeandEnergycollibrationTab);
            title(app.UIAxes_2, 'image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.DosinglemeasurmentButton = uibutton(app.TtimeandEnergycollibrationTab, 'push');
            app.DosinglemeasurmentButton.ButtonPushedFcn = createCallbackFcn(app, @DosinglemeasurmentButtonPushed, true);
            app.DosinglemeasurmentButton.Text = 'Do single measurment';
            app.EnergycollibrationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.EnergycollibrationPanel.Title = 'Energy collibration';
            app.DoenergycollibrationButton = uibutton(app.EnergycollibrationPanel, 'push');
            app.DoenergycollibrationButton.ButtonPushedFcn = createCallbackFcn(app, @DoenergycollibrationButtonPushed, true);
            app.DoenergycollibrationButton.Text = 'Do energy collibration';
            app.upEditFieldLabel = uilabel(app.EnergycollibrationPanel);
            app.upEditFieldLabel.Text = 'up';
            app.upEditField = uieditfield(app.EnergycollibrationPanel, 'numeric');
            app.currentSpinnerLabel = uilabel(app.EnergycollibrationPanel);
            app.currentSpinnerLabel.Text = 'current';
            app.currentSpinner = uispinner(app.EnergycollibrationPanel);
            app.currentSpinner.ValueChangedFcn = createCallbackFcn(app, @currentSpinnerValueChanged, true);
            app.downEditFieldLabel = uilabel(app.EnergycollibrationPanel);
            app.downEditFieldLabel.Text = 'down';
            app.downEditField = uieditfield(app.EnergycollibrationPanel, 'numeric');
            app.TimecollibrationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.TimecollibrationPanel.Title = 'Time collibration';
            app.DotimecollibrationButton = uibutton(app.TimecollibrationPanel, 'push');
            app.DotimecollibrationButton.ButtonPushedFcn = createCallbackFcn(app, @DotimecollibrationButtonPushed, true);
            app.DotimecollibrationButton.Text = 'Do time collibration';
            app.leftEditFieldLabel = uilabel(app.TimecollibrationPanel);
            app.leftEditFieldLabel.Text = 'left';
            app.leftEditField = uieditfield(app.TimecollibrationPanel, 'numeric');
            app.phaseSpinnerLabel = uilabel(app.TimecollibrationPanel);
            app.phaseSpinnerLabel.Text = 'phase';
            app.phaseSpinner = uispinner(app.TimecollibrationPanel);
            app.phaseSpinner.ValueChangingFcn = createCallbackFcn(app, @phaseSpinnerValueChanging, true);
            app.phaseSpinner.Value = 120;
            app.rightEditFieldLabel = uilabel(app.TimecollibrationPanel);
            app.rightEditFieldLabel.Text = 'right';
            app.rightEditField = uieditfield(app.TimecollibrationPanel, 'numeric');
            app.StartCheckBox_2 = uicheckbox(app.TtimeandEnergycollibrationTab);
            app.StartCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.StartCheckBox_2.Text = 'Start ';
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2 = uilabel(app.TtimeandEnergycollibrationTab);
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel_2.Text = 'for online measurment write the correct  range ';
            app.yrangeEditField2 = uieditfield(app.TtimeandEnergycollibrationTab, 'text');
            app.yrangeEditField2.Value = '100';
            app.pulsedurationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.pulsedurationPanel.Title = 'pulse duration ';
            app.stphaseEditFieldLabel = uilabel(app.pulsedurationPanel);
            app.stphaseEditFieldLabel.Text = '1st phase';
            app.stphaseEditField = uieditfield(app.pulsedurationPanel, 'numeric');
            app.ndphaseEditFieldLabel = uilabel(app.pulsedurationPanel);
            app.ndphaseEditFieldLabel.Text = '2nd phase';
            app.ndphaseEditField = uieditfield(app.pulsedurationPanel, 'numeric');
            app.measurmentButton = uibutton(app.pulsedurationPanel, 'push');
            app.measurmentButton.ButtonPushedFcn = createCallbackFcn(app, @measurmentButtonPushed, true);
            app.measurmentButton.Text = 'measurment';
            app.pulsereconstructionTab = uitab(app.TabGroup);
            app.pulsereconstructionTab.Title = 'pulse reconstruction';
            app.UIAxes = uiaxes(app.pulsereconstructionTab);
            title(app.UIAxes, 'image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox = uicheckbox(app.pulsereconstructionTab);
            app.StartCheckBox.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox.Text = 'Start ';
            app.takebackgrounddataButton = uibutton(app.pulsereconstructionTab, 'push');
            app.takebackgrounddataButton.ButtonPushedFcn = createCallbackFcn(app, @takebackgrounddataButtonPushed, true);
            app.takebackgrounddataButton.Text = 'take background data';
            app.collectingdatalasingonButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingonButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingonButtonPushed, true);
            app.collectingdatalasingonButton.Text = 'collecting data lasing on';
            app.collectingdatalasingoffButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingoffButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingoffButtonPushed, true);
            app.collectingdatalasingoffButton.Text = 'collecting data lasing off';
            app.PulsereconstructionButton = uibutton(app.pulsereconstructionTab, 'push');
            app.PulsereconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @PulsereconstructionButtonPushed, true);
            app.PulsereconstructionButton.Text = 'Pulse reconstruction ';
            app.feedbackonphaseCheckBox = uicheckbox(app.pulsereconstructionTab);
            app.feedbackonphaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.feedbackonphaseCheckBox.Text = 'feedback on phase';
            app.analysingimageButton = uibutton(app.pulsereconstructionTab, 'push');
            app.analysingimageButton.ButtonPushedFcn = createCallbackFcn(app, @analysingimageButtonPushed, true);
            app.analysingimageButton.Text = 'analysing image ';
            app.nshotsEditFieldLabel = uilabel(app.pulsereconstructionTab);
            app.nshotsEditFieldLabel.Text = 'n shots';
            app.nshotsEditField = uieditfield(app.pulsereconstructionTab, 'numeric');
            app.nshotsEditField.Value = 10;
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel = uilabel(app.pulsereconstructionTab);
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel.Text = 'for online measurment write the correct  range ';
            app.yrangeEditField = uieditfield(app.pulsereconstructionTab, 'text');
            app.yrangeEditField.Value = '1000';
            app.onlinemeasurmentTab = uitab(app.TabGroup);
            app.onlinemeasurmentTab.Title = 'on line measurment ';
            app.UIAxes5 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes5, 'chirp calculation')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes4, 'current and energy spread')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YAxisLocation = 'right';
            app.UIAxes1 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes1, 'image')
            xlabel(app.UIAxes1, 'time(fs)')
            ylabel(app.UIAxes1, 'energy (MeV)')
            zlabel(app.UIAxes1, 'Z')
            app.STARTButton = uibutton(app.onlinemeasurmentTab, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.logbookButton = uibutton(app.onlinemeasurmentTab, 'push');
            app.logbookButton.ButtonPushedFcn = createCallbackFcn(app, @logbookButtonPushed, true);
            app.logbookButton.Text = 'logbook';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end